classdef mod_afp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        LeftPanel        matlab.ui.container.Panel
        GridLayout3      matlab.ui.container.GridLayout
        ButtonGroup      matlab.ui.container.ButtonGroup
        HzTextArea       matlab.ui.control.TextArea
        HzTextAreaLabel  matlab.ui.control.Label
        EditField        matlab.ui.control.NumericEditField
        Slider           matlab.ui.control.Slider
        SliderLabel      matlab.ui.control.Label
        Button_4         matlab.ui.control.Button
        Button_3         matlab.ui.control.RadioButton
        Button_2         matlab.ui.control.RadioButton
        Button           matlab.ui.control.RadioButton
        UIAxes4          matlab.ui.control.UIAxes
        UIAxes5          matlab.ui.control.UIAxes
        UIAxes3          matlab.ui.control.UIAxes
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
        RightPanel       matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Fs=44100;
        dt=1.0/44100;
        T=1; 
        N=1*44100;             %T/dt;
        signaltype=1;
        A=1;
        t;
        F=10;
        wave;
        P=0;
        Fc=300;
    end
    
    methods (Access = private)
        
        function creatsignal(app)    %生成测量信号
                   % function mySignal(app,type,A,F,P)
            
            app.t=0:app.dt:app.N*app.dt;
            p0=app.P*pi/180;
            if app.signaltype==1
                app.wave=app.A*sin(2*3.14*app.F*app.t+p0);
            end
            if app.signaltype==2
                app.wave=app.A*square(2*3.14*app.F*app.t+p0);
            end
            if app.signaltype==3
                app.wave=app.A*sawtooth(2*3.14*app.F*app.t+p0,0.5);
            end
            y2=ammod(app.wave,app.Fc,app.Fs);
            y3=pmmod(app.wave,app.Fc,app.Fs,3.14);
            y4=fmmod(app.wave,app.Fc,app.Fs,200);
            y5=fmdemod(y4,app.Fc,app.Fs,200);
            plot(app.UIAxes,app.t,app.wave);  %绘制原信号图像
            xlim(app.UIAxes,[0,0.2]);
            plot(app.UIAxes2,app.t,y2);  %绘制调幅图像
            plot(app.UIAxes3,app.t,y3);  %绘制调相位图像
            plot(app.UIAxes4,app.t,y4);  %绘制调频图像
            plot(app.UIAxes5,app.t,y5);  %绘制解调图像
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton==app.Button  %正弦波
                app.signaltype=1;
            end
            if selectedButton==app.Button_2  %方波
                app.signaltype=2;
            end
            if selectedButton==app.Button_3  %三角波
                app.signaltype=3;
            end
            creatsignal(app);

        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            creatsignal(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.Fc=value;
            app.EditField.Value=value;
            creatsignal(app);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
           % value = app.EditField.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {578, 578};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {865, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, '原信号')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 0.2];
            app.UIAxes.YLim = [-1.5 1.5];
            app.UIAxes2 = uiaxes(app.GridLayout3);
            title(app.UIAxes2, '幅值调制')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 0.2];
            app.UIAxes2.YLim = [-1.5 1.5];
            app.UIAxes3 = uiaxes(app.GridLayout3);
            title(app.UIAxes3, '相位调制')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XLim = [0 0.2];
            app.UIAxes3.YLim = [-1.5 1.5];
            app.UIAxes5 = uiaxes(app.GridLayout3);
            title(app.UIAxes5, '解调信号')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5.XLim = [0 0.2];
            app.UIAxes5.YLim = [-1.5 1.5];
            app.UIAxes4 = uiaxes(app.GridLayout3);
            title(app.UIAxes4, '频率调制')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.XLim = [0 0.2];
            app.UIAxes4.YLim = [-1.5 1.5];
            app.ButtonGroup = uibuttongroup(app.GridLayout3);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '信号类型';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = '正弦波';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '方波';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '三角波';
            app.Button_4 = uibutton(app.ButtonGroup, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '开始';
            app.SliderLabel = uilabel(app.ButtonGroup);
            app.SliderLabel.Text = '载波频率';
            app.Slider = uislider(app.ButtonGroup);
            app.Slider.Limits = [0 1000];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 300;
            app.EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.EditField.ValueDisplayFormat = '%.2f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Editable = 'off';
            app.EditField.Value = 300;
            app.HzTextAreaLabel = uilabel(app.ButtonGroup);
            app.HzTextAreaLabel.Text = 'Hz';
            app.HzTextArea = uitextarea(app.ButtonGroup);
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mod_afp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end