classdef test0599 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Label              matlab.ui.control.Label
        EditField          matlab.ui.control.NumericEditField
        Label_4            matlab.ui.control.Label
        EditField_2        matlab.ui.control.NumericEditField
        Label_7            matlab.ui.control.Label
        EditField_3        matlab.ui.control.NumericEditField
        Label_2            matlab.ui.control.Label
        EditField_4        matlab.ui.control.NumericEditField
        Label_5            matlab.ui.control.Label
        EditField_5        matlab.ui.control.NumericEditField
        Label_8            matlab.ui.control.Label
        EditField_6        matlab.ui.control.NumericEditField
        Label_3            matlab.ui.control.Label
        EditField_7        matlab.ui.control.NumericEditField
        Label_6            matlab.ui.control.Label
        EditField_8        matlab.ui.control.NumericEditField
        Label_9            matlab.ui.control.Label
        EditField_9        matlab.ui.control.NumericEditField
        Button             matlab.ui.control.Button
        ButtonGroup        matlab.ui.container.ButtonGroup
        Button_3           matlab.ui.control.RadioButton
        Button_2           matlab.ui.control.RadioButton
        EditField_10Label  matlab.ui.control.Label
        EditField_10       matlab.ui.control.NumericEditField
        UIAxes             matlab.ui.control.UIAxes
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes_3           matlab.ui.control.UIAxes
        UIAxes_4           matlab.ui.control.UIAxes
        UIAxes_5           matlab.ui.control.UIAxes
        UIAxes_6           matlab.ui.control.UIAxes
        UIAxes_7           matlab.ui.control.UIAxes
        UIAxes_8           matlab.ui.control.UIAxes
        Label_10           matlab.ui.control.Label
        EditField_11       matlab.ui.control.NumericEditField
        Label_11           matlab.ui.control.Label
        EditField_12       matlab.ui.control.NumericEditField
        Label_12           matlab.ui.control.Label
        EditField_13       matlab.ui.control.NumericEditField
        Label_13           matlab.ui.control.Label
        EditField_14       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        myt; %时钟中断
        type; %信号类型
        Fs; %采样频率
        N; %采样长度；
        wave1; %信号数组1
        wave2; %信号数组2
        t; %采样数组
        A1; %信号幅值1
        F1; %信号频率1
        A2; %信号幅值2
        F2; %信号频率2
        A3; %信号幅值3
        F3; %信号频率3
        P1; %信号相位1
        P2; %信号相位3
        P3; %信号相位3
        T;%时间
        noise;%白噪声
        
    end
    
    methods (Access = private)
        
        function drawSignal(app)
            %虚拟信号图
            plot(app.UIAxes,app.t,app.wave1);
            xlim(app.UIAxes,[0,0.1]);
            %幅频谱
            y=fft(app.wave1,app.N);
            y(1) = y(1)/2;
            G1=abs(y)/(app.N/2);
            f=linspace(0,app.Fs/2,app.N/2);
            plot(app.UIAxes_5,f,G1(1:app.N/2));
            xlim(app.UIAxes_5,[0 500]);
            %相频谱
            Q1=angle(y)*180/pi;
            plot(app.UIAxes_3,f,Q1(1:app.N/2));
            xlim(app.UIAxes_3,[0 500]);
            %功率谱
            G2=G1.^2;
            plot(app.UIAxes_2,f,G2(1:app.N/2));
            xlim(app.UIAxes_2,[0 500]);
            %实部虚部图
            R=real(y)*2/app.N;I=imag(y)*2/app.N;
            plot(app.UIAxes_4,f(1:app.N/2),R(1:app.N/2));
            xlim(app.UIAxes_4,[0 500]);
            plot(app.UIAxes_6,f(1:app.N/2),I(1:app.N/2));
            xlim(app.UIAxes_6,[0 500]);
            %自相关
            r1=xcorr(app.wave1,"coeff");
            N1=length(r1);
            x1=linspace(-app.T,app.T,N1);
            plot(app.UIAxes_7,x1,r1);
            %互相关
            s1=xcorr(app.wave1,app.noise,"coeff");
            N2=length(s1);
            x2=linspace(-app.T,app.T,N2);
            plot(app.UIAxes_8,x2,s1);
            %峰值、峰峰值、有效值、平均值
            app.EditField_10.Value=max(app.wave1);
            app.EditField_11.Value=max(app.wave1)-min(app.wave1);
            app.EditField_12.Value=sqrt(mean(app.wave1.^2));
            app.EditField_13.Value=mean(app.wave1);
            %过零点算法
            p=max(app.wave1);
            q=min(app.wave1);
            n=1;
            at=0.1*(p-q)+q;
            for k=2:1:app.N-1
                if (app.wave1(k-1)<at && app.wave1(k)<=at && app.wave1(k+1)>at && app.wave1(k+2)>at)
                    ti(n)=k;
                    n=n+1;
                end
            end
            T1=(ti(2)-ti(1))/app.Fs;
            app.EditField_14.Value=1/T1;
            
        end
        
        function mySignal(app,type,A1,F1,P1,A2,F2,P2,A3,F3,P3)
            
            if type==0
                dt=1.0/app.Fs;
                app.T=app.N*dt;
                app.t=0:dt:app.T;
                y1=A1*sin(2*3.14*F1*app.t+P1);
                y2=A2*sin(2*3.14*F2*app.t+P2);
                y3=A3*sin(2*3.14*F3*app.t+P3);
                app.wave1=y1+y2+y3;
                app.noise=randn(1,app.N+1);
            end
            if type==1
                NetId=[5,114,102,232,1,1];
                app.wave1=MatlabTwincatReadAds(NetId,'matrix','MAIN.DataPool[1].signalGen',1000);
                dt=1.0/app.Fs;
                app.T=app.N*dt;
                app.t=linspace(0,app.T,1000);%0:dt:app.T;
                app.noise=randn(1,app.N);
                
            end


        end
        
end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.type=0;
            app.Fs=10000;
            app.N=1000;
            app.A1=4;app.F1=100;app.P1=30;
            app.A2=8;app.F2=200;app.P2=60;
            app.A3=10;app.F3=300;app.P3=90; 
            mySignal(app,app.type,app.A1,app.F1,app.P1,app.A2,app.F2,app.P2,app.A3,app.F3,app.P3);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             mySignal(app,app.type,app.A1,app.F1,app.P1,app.A2,app.F2,app.P2,app.A3,app.F3,app.P3);
            drawSignal(app);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.F1 = app.EditField.Value;
            mySignal(app,app.type,app.A1,app.F1,app.P1,app.A2,app.F2,app.P2,app.A3,app.F3,app.P3);
            drawSignal(app);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.F1 = app.EditField_2.Value;
             mySignal(app,app.type,app.A1,app.F1,app.P1,app.A2,app.F2,app.P2,app.A3,app.F3,app.P3);
            drawSignal(app);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
           app.P1 = app.EditField_3.Value;
             mySignal(app,app.type,app.A1,app.F1,app.P1,app.A2,app.F2,app.P2,app.A3,app.F3,app.P3);
            drawSignal(app);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            app.F2 = app.EditField_4.Value;
             mySignal(app,app.type,app.A1,app.F1,app.P1,app.A2,app.F2,app.P2,app.A3,app.F3,app.P3);
            drawSignal(app);
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            app.A2 = app.EditField_5.Value;
             mySignal(app,app.type,app.A1,app.F1,app.P1,app.A2,app.F2,app.P2,app.A3,app.F3,app.P3);
            drawSignal(app);
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
           app.P2 = app.EditField_6.Value;
             mySignal(app,app.type,app.A1,app.F1,app.P1,app.A2,app.F2,app.P2,app.A3,app.F3,app.P3);
            drawSignal(app);
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
           app.F3 = app.EditField_7.Value;
             mySignal(app,app.type,app.A1,app.F1,app.P1,app.A2,app.F2,app.P2,app.A3,app.F3,app.P3);
            drawSignal(app);
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            app.A3 = app.EditField_8.Value;
             mySignal(app,app.type,app.A1,app.F1,app.P1,app.A2,app.F2,app.P2,app.A3,app.F3,app.P3);
            drawSignal(app);
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            app.P3 = app.EditField_9.Value;
             mySignal(app,app.type,app.A1,app.F1,app.P1,app.A2,app.F2,app.P2,app.A3,app.F3,app.P3);
            drawSignal(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton==app.Button_2
            app.type=0;
            end
            
            if selectedButton==app.Button_3
            app.type=1;
            end
             mySignal(app,app.type,app.A1,app.F1,app.P1,app.A2,app.F2,app.P2,app.A3,app.F3,app.P3);
            drawSignal(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '信号1频率';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 100;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '信号1幅值';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 4;
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '信号1初相位';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 30;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '信号2频率';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Value = 200;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '信号2幅值';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Value = 8;
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '信号2初相位';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Value = 60;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '信号3频率';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_7.Value = 300;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '信号3幅值';
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_8.Value = 10;
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '信号3初相位';
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.EditField_9.Value = 90;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '运行';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '信号';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '实际信号';
            app.Button_3.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '虚拟信号';
            app.Button_2.Value = true;
            app.EditField_10Label = uilabel(app.UIFigure);
            app.EditField_10Label.Text = '峰值';
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_10.ValueDisplayFormat = '%.6f';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '信号')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '功率谱')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '相频谱')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, '实频谱')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, '幅频谱')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, '虚频谱')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, '自相关')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, '互相关')
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '峰峰值';
            app.EditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_11.ValueDisplayFormat = '%.6f';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '有效值';
            app.EditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_12.ValueDisplayFormat = '%.6f';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '平均值';
            app.EditField_13 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_13.ValueDisplayFormat = '%.6f';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '计算频率';
            app.EditField_14 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_14.ValueDisplayFormat = '%.6f';
        end
    end

    methods (Access = public)

        % Construct app
        function app = test0599

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end