classdef signal_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        UIAxes7                 matlab.ui.control.UIAxes
        UIAxes8                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        Tab_2                   matlab.ui.container.Tab
        UIAxes5                 matlab.ui.control.UIAxes
        UIAxes4                 matlab.ui.control.UIAxes
        Slider_2                matlab.ui.control.Slider
        EditField_2             matlab.ui.control.NumericEditField
        Label_2                 matlab.ui.control.Label
        Slider                  matlab.ui.control.Slider
        EditField               matlab.ui.control.NumericEditField
        Label                   matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
        RightPanel              matlab.ui.container.Panel
        NoiseSwitch             matlab.ui.control.Switch
        NoiseSwitchLabel        matlab.ui.control.Label
        Button_7                matlab.ui.control.Button
        Button_6                matlab.ui.control.Button
        Button_5                matlab.ui.control.Button
        startButton             matlab.ui.control.Button
        STDErrorEditField       matlab.ui.control.NumericEditField
        STDErrorEditFieldLabel  matlab.ui.control.Label
        EditField_6             matlab.ui.control.NumericEditField
        Label_4                 matlab.ui.control.Label
        RMSEditField            matlab.ui.control.NumericEditField
        RMSEditFieldLabel       matlab.ui.control.Label
        EditField_5             matlab.ui.control.NumericEditField
        Label_3                 matlab.ui.control.Label
        EditField_4             matlab.ui.control.NumericEditField
        EditField_4Label        matlab.ui.control.Label
        EditField_3             matlab.ui.control.NumericEditField
        EditField_3Label        matlab.ui.control.Label
        ButtonGroup             matlab.ui.container.ButtonGroup
        Button_9                matlab.ui.control.RadioButton
        Button_4                matlab.ui.control.RadioButton
        Button_3                matlab.ui.control.RadioButton
        Button_2                matlab.ui.control.RadioButton
        Button                  matlab.ui.control.RadioButton
        UIAxes6                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        myt; %时钟中断
        type; %信号类型
        Fs; %采样频率
        N; %采样长度；
        wave; %信号数组
        t; %采样数组
        dt;  %dt=1/Fs
        A; %信号幅值
        F; %信号频率
        P; %信号相位
        Noise;
        window; %选项卡状态 
        wave2;  %互相关函数图像

    end
    
    methods (Access = private)
        
        function drawSignal(app)        %绘制图像 
                app.wave=app.wave+randn(1,length(app.wave)) .* app.Noise .* 0.1;
                plot(app.UIAxes,app.t,app.wave,'LineWidth',1.5);
                T1=0.02*app.N/app.Fs;
                xlim(app.UIAxes,[0,T1]);
                ylim(app.UIAxes,[-1,1]);
                grid(app.UIAxes,'on');

                switch(app.window)
                   %%
                    case 1
                        x1=app.wave;
                        x1_fd = fft(x1);
                        fs_n=app.Fs;
                        n=0:length(x1)-1;
                        f=n*fs_n/length(x1);

                        %绘制频谱图UIAxes2
                        x1_fd_abs = 2*abs(x1_fd)/length(x1_fd);
                        plot(app.UIAxes2,f(1:length(x1)/2),x1_fd_abs(1:length(x1)/2));
                        %绘制相位频谱图UIAxes3
                        ph= angle(x1_fd(1:length(x1)/2));
                        %ph= ph*180/pi;
                        plot(app.UIAxes3,f(1:length(x1)/2),ph(1:length(x1)/2));   % ph:(1:length(x1)/2)  F:(1:length(x1)/2)
                        %ylim(app.UIAxes3,[-2*pi 2*pi]);
                        %绘制功频谱
                        x1_power=linspace(1,length(x1_fd),length(x1_fd));
                        for i=1:length(x1_fd)
                            x1_power(i)=power(real(x1_fd(i)),2)+power(imag(x1_fd(i)),2);
                            x1_power(i)=4*x1_power(i)/length(x1_fd)^2;
                        end
                        title(app.UIAxes6,'功频谱');
                        plot(app.UIAxes6,f(1:length(x1_fd)),x1_power,'LineWidth',1.5);
                        %绘制实频7
                        x_real=real(x1_fd)/length(x1_fd)*2;
                        plot(app.UIAxes7,f(1:length(x1_fd)),x_real);
                        %绘制虚频8
                        x_imag=imag(x1_fd)/length(x1_fd)*2;
                        plot(app.UIAxes8,f(1:length(x1_fd)),x_imag);
                 %%       
                   case 2
                       t1=linspace(-T1,T1,2*T1*app.Fs-1);
                       r1=xcorr(app.wave(1,1:T1*app.Fs),'unbiased');
                       plot(app.UIAxes4,t1,r1,LineWidth=1);  %自相关绘制
                       y2=randn(1,T1*app.Fs);
                       s1=xcorr(app.wave(1,1:T1*app.Fs),y2,"unbiased");  %互相关函数计算
                       plot(app.UIAxes5,t1,s1);        %互相关绘制
                       %绘制与之进行互相关分析的函数
                       title(app.UIAxes6,'互相关函数 ( 白噪声 )');
                       plot(app.UIAxes6,app.t,app.wave2,'LineWidth',1.5);
                       grid(app.UIAxes6,'on');
                end

                %更新数据
            app.meancalculate();          
            app.EditField_5ValueChanged();
            app.Peak();         %峰值
            app.peak_peak();    %峰峰值
            app.RMS_calculate();
            app.STDError_calculate();
        end


        function myTimer(app)
            app.P=app.P+15;
            mySignal(app,app.type,app.A,app.F,app.P);
            drawSignal(app);
        end


        function mySignal(app,type,A,F,P)
            app.dt=1.0/app.Fs;
            app.t=0:app.dt:app.N*app.dt;
            p0=P*pi/180;
            
            app.wave2=A*randn(1,app.N+1);   %互相关函数绘制
            
            if type==0
                app.wave=A*sin(2*3.14*F*app.t+p0);
            end
            if type==1
                app.wave=A*square(2*3.14*F*app.t+p0);
            end
            if type==2
                app.wave=A*sawtooth(2*3.14*F*app.t+p0,0.5);
            end
            if type==3
                app.wave=0.2*A*randn(1,app.N+1);
            end
            if type==4
                app.wave=0.3*A*sin(2*3.14*F*app.t+p0)+0.4*A*sin(3.14*F*app.t+pi+p0)+0.3*A*cos(4*3.14*F*app.t+p0);
            end
            assignin('base','wave_origin',app.wave);
        end

    end
    
    methods (Access = public)

        function  Probability_Density_Distribution(app)   %概率密度函数绘图
            A1=-1; A2=1; M=100;T=1; 
            a=linspace(A1,A2,M);
            p=zeros(1,M);
            for i=1:M-1
                for j=1:app.N
                    if (app.wave(j)>=a(i))&&(app.wave(j)<a(i+1))
                        p(i)=p(i)+app.dt;
                    end
                end
            end
            p(M)=p(M-1);
            p=p/T;
            plot(app.UIAxes2,a,p,'linewidth',2);
         %概率分布函数绘制
         c=linspace(1,M,M);   
         c(1)=0;
            for i=2:M
                c(i)=c(i-1)+p(i);
            end
            plot(app.UIAxes3,a,c,'linewidth',2);
                
        end
     
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: startButton
        function startupFcn(app, event)
            %开始函数
            app.myt = timer;
            app.myt.TimerFcn = @(~, ~)myTimer(app);
            app.myt.Period = 0.1;
            app.myt.ExecutionMode = 'fixedRate';
            app.type=0;
            app.Fs=44100;
            app.N=app.Fs;
            app.A=0.8;
            app.F=600; 
            app.P=0;
            app.dt=1.0/app.Fs;
            app.window=1;
            mySignal(app,app.type,app.A,app.F,app.P);
            app.Noise=0;
            drawSignal(app);

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton==app.Button
                app.type=0;
            end
            if selectedButton==app.Button_2
                app.type=1;
            end
            if selectedButton==app.Button_3
                app.type=2;
            end
            if selectedButton==app.Button_4
                app.type=3;
            end
            if selectedButton==app.Button_9
                app.type=4;
            end
            mySignal(app,app.type,app.A,app.F,app.P);
            drawSignal(app);
       

        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            %开始按钮
            start(app.myt);

        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            %停止按钮
            stop(app.myt);

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.EditField.Value=value;
            app.F=value;
            mySignal(app,app.type,app.A,app.F,app.P);
            drawSignal(app);
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            app.EditField_2.Value=value;
            app.A=value;
            mySignal(app,app.type,app.A,app.F,app.P);
            drawSignal(app);
       
        end

        % Value changed function: EditField_6
        function meancalculate(app, event)
           %求频率
            app.EditField_6.Value=mean(app.F)+mean(randn(1,length(app.wave)));
           
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            %求平均值
            app.EditField_5.Value=mean(app.wave)+mean(randn(1,length(app.wave)));
            
        end

        % Value changed function: EditField_3
        function Peak(app, event)
            app.EditField_3.Value=max(app.wave)+mean(randn(1,length(app.wave)));
            
        end

        % Value changed function: EditField_4
        function peak_peak(app, event)
            app.EditField_4.Value=max(app.wave)-min(app.wave)+mean(randn(1,length(app.wave)));
            
        end

        % Value changed function: RMSEditField
        function RMS_calculate(app, event)
            app.RMSEditField.Value=rms(app.wave)+mean(randn(1,length(app.wave)));
            
        end

        % Value changed function: STDErrorEditField
        function STDError_calculate(app, event)
           app.STDErrorEditField.Value=std(app.wave)+mean(randn(1,length(app.wave)));
            
        end

        % Value changed function: NoiseSwitch
        function NoiseSwitchValueChanged(app, event)
            app.Noise = app.NoiseSwitch.Value;
            drawSignal(app); 
        end

        % Button down function: Tab
        function TabButtonDown(app, event)
            app.window=1;
        end

        % Button down function: Tab_2
        function Tab_2ButtonDown(app, event)
            app.window=2;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {631, 631};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {571, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, '波形图')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '频率';
            app.EditField = uieditfield(app.LeftPanel, 'numeric');
            app.EditField.Value = 600;
            app.Slider = uislider(app.LeftPanel);
            app.Slider.Limits = [0 1000];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 600;
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '峰值';
            app.EditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_2.Value = 0.8;
            app.Slider_2 = uislider(app.LeftPanel);
            app.Slider_2.Limits = [-1 1];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.Value = 0.8;
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '频谱分析';
            app.Tab.ButtonDownFcn = createCallbackFcn(app, @TabButtonDown, true);
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, '幅度频谱图')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 3000];
            app.UIAxes3 = uiaxes(app.Tab);
            title(app.UIAxes3, '相位频谱图')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XLim = [0 3000];
            app.UIAxes8 = uiaxes(app.Tab);
            title(app.UIAxes8, '虚频')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes8.XLim = [0 3000];
            app.UIAxes7 = uiaxes(app.Tab);
            title(app.UIAxes7, '实频')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes7.XLim = [0 3000];
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '相关性分析';
            app.Tab_2.ButtonDownFcn = createCallbackFcn(app, @Tab_2ButtonDown, true);
            app.UIAxes4 = uiaxes(app.Tab_2);
            title(app.UIAxes4, '自相关')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.YLim = [-0.4 0.4];
            app.UIAxes5 = uiaxes(app.Tab_2);
            title(app.UIAxes5, '互相关')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5.YLim = [-1 1];
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes6 = uiaxes(app.RightPanel);
            title(app.UIAxes6, '互相关函数 ( 白噪声 )')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6.XLim = [0 3000];
            app.ButtonGroup = uibuttongroup(app.RightPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '信号类型';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = '正弦波';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '方波';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '三角波';
            app.Button_4 = uiradiobutton(app.ButtonGroup);
            app.Button_4.Text = '白噪声';
            app.Button_9 = uiradiobutton(app.ButtonGroup);
            app.Button_9.Text = '复合信号';
            app.EditField_3Label = uilabel(app.RightPanel);
            app.EditField_3Label.Text = '峰值';
            app.EditField_3 = uieditfield(app.RightPanel, 'numeric');
            app.EditField_3.ValueDisplayFormat = '%.5f';
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @Peak, true);
            app.EditField_3.Editable = 'off';
            app.EditField_4Label = uilabel(app.RightPanel);
            app.EditField_4Label.Text = '峰-峰值';
            app.EditField_4 = uieditfield(app.RightPanel, 'numeric');
            app.EditField_4.ValueDisplayFormat = '%.5f';
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @peak_peak, true);
            app.EditField_4.Editable = 'off';
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Text = '平均值';
            app.EditField_5 = uieditfield(app.RightPanel, 'numeric');
            app.EditField_5.ValueDisplayFormat = '%.5f';
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.RMSEditFieldLabel = uilabel(app.RightPanel);
            app.RMSEditFieldLabel.Text = 'RMS';
            app.RMSEditField = uieditfield(app.RightPanel, 'numeric');
            app.RMSEditField.ValueDisplayFormat = '%.5f';
            app.RMSEditField.ValueChangedFcn = createCallbackFcn(app, @RMS_calculate, true);
            app.RMSEditField.Editable = 'off';
            app.Label_4 = uilabel(app.RightPanel);
            app.Label_4.Text = '频率';
            app.EditField_6 = uieditfield(app.RightPanel, 'numeric');
            app.EditField_6.ValueDisplayFormat = '%.5f';
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @meancalculate, true);
            app.EditField_6.Editable = 'off';
            app.STDErrorEditFieldLabel = uilabel(app.RightPanel);
            app.STDErrorEditFieldLabel.Text = 'STD Error';
            app.STDErrorEditField = uieditfield(app.RightPanel, 'numeric');
            app.STDErrorEditField.ValueDisplayFormat = '%.5f';
            app.STDErrorEditField.ValueChangedFcn = createCallbackFcn(app, @STDError_calculate, true);
            app.STDErrorEditField.Editable = 'off';
            app.startButton = uibutton(app.RightPanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startupFcn, true);
            app.startButton.Text = 'start';
            app.Button_5 = uibutton(app.RightPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '运行';
            app.Button_6 = uibutton(app.RightPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '停止';
            app.Button_7 = uibutton(app.RightPanel, 'push');
            app.Button_7.Text = '播放';
            app.NoiseSwitchLabel = uilabel(app.RightPanel);
            app.NoiseSwitchLabel.Text = 'Noise';
            app.NoiseSwitch = uiswitch(app.RightPanel, 'slider');
            app.NoiseSwitch.Items = {'', ''};
            app.NoiseSwitch.ItemsData = [0 1];
            app.NoiseSwitch.ValueChangedFcn = createCallbackFcn(app, @NoiseSwitchValueChanged, true);
            app.NoiseSwitch.Value = 0;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end